classdef ProjectUFC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomePage                       matlab.ui.container.Tab
        AldoIbarraLabel                matlab.ui.control.Label
        NoahThompsonLabel              matlab.ui.control.Label
        Group17Label                   matlab.ui.control.Label
        title_label                    matlab.ui.control.Label
        belt_image                     matlab.ui.control.Image
        Head2Head                      matlab.ui.container.Tab
        LossesEditField_2              matlab.ui.control.NumericEditField
        LossesEditField_2Label         matlab.ui.control.Label
        LossesEditField                matlab.ui.control.NumericEditField
        LossesEditFieldLabel           matlab.ui.control.Label
        Fighter2DropDown               matlab.ui.control.DropDown
        Fighter2DropDownLabel          matlab.ui.control.Label
        Fighter1DropDown               matlab.ui.control.DropDown
        Fighter1DropDownLabel          matlab.ui.control.Label
        WinnerEditField                matlab.ui.control.EditField
        PredictedWinnerEditFieldLabel  matlab.ui.control.Label
        WinsEditField_2                matlab.ui.control.EditField
        WinsEditField_2Label           matlab.ui.control.Label
        WeightEditField_2              matlab.ui.control.EditField
        WeightlbsEditField_2Label      matlab.ui.control.Label
        HeightEditField_2              matlab.ui.control.EditField
        HeightcmEditField_2Label       matlab.ui.control.Label
        NicknameEditField_2            matlab.ui.control.EditField
        NicknameEditField_2Label       matlab.ui.control.Label
        WinsEditField                  matlab.ui.control.EditField
        WinsEditFieldLabel             matlab.ui.control.Label
        WeightEditField                matlab.ui.control.EditField
        WeightlbsEditFieldLabel        matlab.ui.control.Label
        HeightEditField                matlab.ui.control.EditField
        HeightcmEditFieldLabel         matlab.ui.control.Label
        NicknameEditField              matlab.ui.control.EditField
        NicknameEditFieldLabel         matlab.ui.control.Label
        SubmitFightersButton           matlab.ui.control.Button
        WhoWouldWinLabel               matlab.ui.control.Label
        fighter2_image                 matlab.ui.control.Image
        fighter1_image                 matlab.ui.control.Image
        FighterStanceToWins            matlab.ui.container.Tab
        NumberofFightersWithThisStanceLabel  matlab.ui.control.Label
        NumberOfFightersEditField      matlab.ui.control.NumericEditField
        WhichStanceDoYouWantButtonGroup  matlab.ui.container.ButtonGroup
        SwitchButton                   matlab.ui.control.RadioButton
        SouthpawButton                 matlab.ui.control.RadioButton
        OrthodoxButton                 matlab.ui.control.RadioButton
        UFCFightersWinsLossesDrawsbyDifferentStancesLabel  matlab.ui.control.Label
        StanceVsRecordPlot             matlab.ui.control.UIAxes
        WingspanWinsTab                matlab.ui.container.Tab
        CorrelationBetweenWingspanWinsinDifferentWeightClassesLabel  matlab.ui.control.Label
        WeightClassDropDown            matlab.ui.control.DropDown
        WeightClassDropDownLabel       matlab.ui.control.Label
        WingspanScatter                matlab.ui.control.UIAxes
        FighterStanceAgeTab            matlab.ui.container.Tab
        SelectedYearEditField          matlab.ui.control.NumericEditField
        SelectedYearEditFieldLabel     matlab.ui.control.Label
        BirthYearSlider                matlab.ui.control.Slider
        BirthYearSliderLabel           matlab.ui.control.Label
        RelationshipBetweenFighterStanceBirthYearLabel  matlab.ui.control.Label
        FightersByAgePlot              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % ufc belt image on home page as a startup 
            app.belt_image.ImageSource = 'new-ufc-championship-title-belt.jpg';

            app.fighter1_image.ImageSource = 'UFC Logo.jpeg';
            app.fighter2_image.ImageSource = 'UFC Logo.jpeg';
        end

        % Value changed function: Fighter1DropDown
        function Fighter1DropDownValueChanged(app, event)
            value = app.Fighter1DropDown.Value;
            dropDownFighterInfoF (app)
   % ufc fighter images based off of selected fighter
            if strcmp(value, 'Islam Makhachev')
                app.fighter1_image.ImageSource = 'Islam_Makhachev.jpeg';
            elseif strcmp(value, 'Jon Jones')
                app.fighter1_image.ImageSource = 'Jon_Jones.png';
            elseif strcmp(value, 'Leon Edwards')
                app.fighter1_image.ImageSource = 'Leon_Edwards.png';
            elseif strcmp(value, 'Alex Pereira')
                app.fighter1_image.ImageSource = 'Alex_Pereira.png';
            elseif strcmp(value, 'Ilia Topuria')
                app.fighter1_image.ImageSource = 'Ilia_Topuria.png';
            elseif strcmp(value, 'Sean O''Malley')
                app.fighter1_image.ImageSource = 'Sean_OMalley.png';
            elseif strcmp(value, 'Charles Oliveira')
                app.fighter1_image.ImageSource = 'Charles_Oliveira.png';
            elseif strcmp(value, 'Alexander Volkanovski')
                app.fighter1_image.ImageSource = 'Volk.png';
            elseif strcmp(value, 'Dricus Du Plessis')
                app.fighter1_image.ImageSource = 'Dricus.png';
            elseif strcmp(value, 'Alexandre Pantoja')
                app.fighter1_image.ImageSource = 'Pantoja.png';
            elseif strcmp(value, 'Israel Adesanya')
                app.fighter1_image.ImageSource = 'Adesanya.png';
            elseif strcmp(value, 'Tom Aspinall')
                app.fighter1_image.ImageSource = 'Aspinall.png';
            elseif strcmp(value, 'Sean Strickland')
                app.fighter1_image.ImageSource = 'Strickland.png';
            elseif strcmp(value, 'Max Holloway')
                app.fighter1_image.ImageSource = 'Holloway.png';
            elseif strcmp(value, 'Aljamain Sterling')
                app.fighter1_image.ImageSource = 'Sterling.png';
            elseif strcmp(value, 'Georges St-Pierre')
                app.fighter1_image.ImageSource = 'GSP.jpeg';
            elseif strcmp(value, 'Khabib Nurmagomedov')
                app.fighter1_image.ImageSource = 'Khabib.jpeg';
            elseif strcmp(value, 'Jorge Masvidal')
                app.fighter1_image.ImageSource = 'Masvidal.png';
            elseif strcmp(value, 'Francis Ngannou')
                app.fighter1_image.ImageSource = 'Ngannou.png';
            elseif strcmp(value, 'Conor McGregor')
                app.fighter1_image.ImageSource = 'McGregor.png';
            elseif strcmp(value, 'Justin Gaethje')
                app.fighter1_image.ImageSource = 'Gaethje.png';
            elseif strcmp(value, 'Stipe Miocic')
                app.fighter1_image.ImageSource = 'Stipe.jpeg';
            elseif strcmp(value, 'Dustin Poirier')
                app.fighter1_image.ImageSource = 'Poirier.png';
            elseif strcmp(value, 'Brandon Moreno')
                app.fighter1_image.ImageSource = 'Moreno.png';
            elseif strcmp(value, 'Merab Dvalishvili')
                app.fighter1_image.ImageSource = 'Merab.png';
            elseif strcmp(value, 'Cory Sandhagen')
                app.fighter1_image.ImageSource = 'Sandhagen.png';
            elseif strcmp(value, 'Brian Ortega')
                app.fighter1_image.ImageSource = 'Brian_Ortega.png';
            elseif strcmp(value, 'Kamaru Usman')
                app.fighter1_image.ImageSource = 'Usman.png';
            elseif strcmp(value, 'Robert Whittaker')
                app.fighter1_image.ImageSource = 'Whittaker.png';
            elseif strcmp(value, 'Ciryl Gane')
                app.fighter1_image.ImageSource = 'Gane.png';
            end

        end

        % Value changed function: Fighter2DropDown
        function Fighter2DropDownValueChanged(app, event)
            value = app.Fighter2DropDown.Value;
            dropDownFighterInfoF (app)
  % ufc fighter 2 image based off of selected fighter          
            if strcmp(value, 'Islam Makhachev')
                app.fighter2_image.ImageSource = 'Islam_Makhachev.jpeg';
            elseif strcmp(value, 'Jon Jones')
                app.fighter2_image.ImageSource = 'Jon_Jones.png';
            elseif strcmp(value, 'Leon Edwards')
                app.fighter2_image.ImageSource = 'Leon_Edwards.png';
            elseif strcmp(value, 'Alex Pereira')
                app.fighter2_image.ImageSource = 'Alex_Pereira.png';
            elseif strcmp(value, 'Ilia Topuria')
                app.fighter2_image.ImageSource = 'Ilia_Topuria.png';
            elseif strcmp(value, 'Sean O''Malley')
                app.fighter2_image.ImageSource = 'Sean_OMalley.png';
            elseif strcmp(value, 'Charles Oliveira')
                app.fighter2_image.ImageSource = 'Charles_Oliveira.png';
            elseif strcmp(value, 'Alexander Volkanovski')
                app.fighter2_image.ImageSource = 'Volk.png';
            elseif strcmp(value, 'Dricus Du Plessis')
                app.fighter2_image.ImageSource = 'Dricus.png';
            elseif strcmp(value, 'Alexandre Pantoja')
                app.fighter2_image.ImageSource = 'Pantoja.png';
            elseif strcmp(value, 'Israel Adesanya')
                app.fighter2_image.ImageSource = 'Adesanya.png';
            elseif strcmp(value, 'Tom Aspinall')
                app.fighter2_image.ImageSource = 'Aspinall.png';
            elseif strcmp(value, 'Sean Strickland')
                app.fighter2_image.ImageSource = 'Strickland.png';
            elseif strcmp(value, 'Max Holloway')
                app.fighter2_image.ImageSource = 'Holloway.png';
            elseif strcmp(value, 'Aljamain Sterling')
                app.fighter2_image.ImageSource = 'Sterling.png';
            elseif strcmp(value, 'Georges St-Pierre')
                app.fighter2_image.ImageSource = 'GSP.jpeg';
            elseif strcmp(value, 'Khabib Nurmagomedov')
                app.fighter2_image.ImageSource = 'Khabib.jpeg';
            elseif strcmp(value, 'Jorge Masvidal')
                app.fighter2_image.ImageSource = 'Masvidal.png';
            elseif strcmp(value, 'Francis Ngannou')
                app.fighter2_image.ImageSource = 'Ngannou.png';
            elseif strcmp(value, 'Conor McGregor')
                app.fighter2_image.ImageSource = 'McGregor.png';
            elseif strcmp(value, 'Justin Gaethje')
                app.fighter2_image.ImageSource = 'Gaethje.png';
            elseif strcmp(value, 'Stipe Miocic')
                app.fighter2_image.ImageSource = 'Stipe.jpeg';
            elseif strcmp(value, 'Dustin Poirier')
                app.fighter2_image.ImageSource = 'Poirier.png';
            elseif strcmp(value, 'Brandon Moreno')
                app.fighter2_image.ImageSource = 'Moreno.png';
            elseif strcmp(value, 'Merab Dvalishvili')
                app.fighter2_image.ImageSource = 'Merab.png';
            elseif strcmp(value, 'Cory Sandhagen')
                app.fighter2_image.ImageSource = 'Sandhagen.png';
            elseif strcmp(value, 'Brian Ortega')
                app.fighter2_image.ImageSource = 'Brian_Ortega.png';
            elseif strcmp(value, 'Kamaru Usman')
                app.fighter2_image.ImageSource = 'Usman.png';
            elseif strcmp(value, 'Robert Whittaker')
                app.fighter2_image.ImageSource = 'Whittaker.png';
            elseif strcmp(value, 'Ciryl Gane')
                app.fighter2_image.ImageSource = 'Gane.png';
            end            
        end

        % Button pushed function: SubmitFightersButton
        function SubmitFightersButtonPushed(app, event)
            fightWinnerF(app)
        end

        % Selection changed function: WhichStanceDoYouWantButtonGroup
        function WhichStanceDoYouWantButtonGroupSelectionChanged(app, event)
            %radio button callback
            recordByStanceF(app)
            selectedButton = app.WhichStanceDoYouWantButtonGroup.SelectedObject;
            
        end

        % Value changed function: WeightClassDropDown
        function WeightClassDropDownValueChanged(app, event)
            value = app.WeightClassDropDown.Value;
            wingSpanWinsF (app)
        end

        % Value changed function: BirthYearSlider
        function BirthYearSliderValueChanged(app, event)
            value = app.BirthYearSlider.Value;
            % connect slider to selected year edit field
            app.SelectedYearEditField.Value = value;
            fighterAgeVsStanceF (app)
        end

        % Value changed function: SelectedYearEditField
        function SelectedYearEditFieldValueChanged(app, event)
            value = app.SelectedYearEditField.Value;
            % link edit field to the slider
            app.BirthYearSlider.Value = value;
            fighterAgeVsStanceF (app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomePage = uitab(app.TabGroup);
            app.HomePage.Title = 'Home Page';
            app.belt_image = uiimage(app.HomePage);
            app.title_label = uilabel(app.HomePage);
            app.title_label.Text = 'UFC Statistics and Analytics';
            app.Group17Label = uilabel(app.HomePage);
            app.Group17Label.Text = 'Group 17';
            app.NoahThompsonLabel = uilabel(app.HomePage);
            app.NoahThompsonLabel.Text = 'Noah Thompson';
            app.AldoIbarraLabel = uilabel(app.HomePage);
            app.AldoIbarraLabel.Text = 'Aldo Ibarra';
            app.Head2Head = uitab(app.TabGroup);
            app.Head2Head.Title = 'Head to Head';
            app.fighter1_image = uiimage(app.Head2Head);
            app.fighter2_image = uiimage(app.Head2Head);
            app.WhoWouldWinLabel = uilabel(app.Head2Head);
            app.WhoWouldWinLabel.Text = 'Who Would Win?';
            app.SubmitFightersButton = uibutton(app.Head2Head, 'push');
            app.SubmitFightersButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitFightersButtonPushed, true);
            app.SubmitFightersButton.Text = 'Submit Fighters!';
            app.NicknameEditFieldLabel = uilabel(app.Head2Head);
            app.NicknameEditFieldLabel.Text = 'Nickname';
            app.NicknameEditField = uieditfield(app.Head2Head, 'text');
            app.HeightcmEditFieldLabel = uilabel(app.Head2Head);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.HeightEditField = uieditfield(app.Head2Head, 'text');
            app.WeightlbsEditFieldLabel = uilabel(app.Head2Head);
            app.WeightlbsEditFieldLabel.Text = 'Weight (lbs)';
            app.WeightEditField = uieditfield(app.Head2Head, 'text');
            app.WinsEditFieldLabel = uilabel(app.Head2Head);
            app.WinsEditFieldLabel.Text = 'Wins';
            app.WinsEditField = uieditfield(app.Head2Head, 'text');
            app.NicknameEditField_2Label = uilabel(app.Head2Head);
            app.NicknameEditField_2Label.Text = 'Nickname';
            app.NicknameEditField_2 = uieditfield(app.Head2Head, 'text');
            app.HeightcmEditField_2Label = uilabel(app.Head2Head);
            app.HeightcmEditField_2Label.Text = 'Height (cm)';
            app.HeightEditField_2 = uieditfield(app.Head2Head, 'text');
            app.WeightlbsEditField_2Label = uilabel(app.Head2Head);
            app.WeightlbsEditField_2Label.Text = 'Weight (lbs)';
            app.WeightEditField_2 = uieditfield(app.Head2Head, 'text');
            app.WinsEditField_2Label = uilabel(app.Head2Head);
            app.WinsEditField_2Label.Text = 'Wins';
            app.WinsEditField_2 = uieditfield(app.Head2Head, 'text');
            app.PredictedWinnerEditFieldLabel = uilabel(app.Head2Head);
            app.PredictedWinnerEditFieldLabel.Text = 'Predicted Winner!';
            app.WinnerEditField = uieditfield(app.Head2Head, 'text');
            app.Fighter1DropDownLabel = uilabel(app.Head2Head);
            app.Fighter1DropDownLabel.Text = 'Fighter 1';
            app.Fighter1DropDown = uidropdown(app.Head2Head);
            app.Fighter1DropDown.Items = {'Islam Makhachev', 'Jon Jones', 'Leon Edwards', 'Alex Pereira', 'Ilia Topuria', 'Sean O''Malley', 'Charles Oliveira', 'Alexander Volkanovski', 'Dricus Du Plessis', 'Alexandre Pantoja', 'Israel Adesanya', 'Tom Aspinall', 'Sean Strickland', 'Max Holloway', 'Aljamain Sterling', 'Georges St-Pierre', 'Khabib Nurmagomedov', 'Jorge Masvidal', 'Francis Ngannou', 'Conor McGregor', 'Justin Gaethje', 'Stipe Miocic', 'Dustin Poirier', 'Brandon Moreno', 'Merab Dvalishvili', 'Cory Sandhagen', 'Brian Ortega', 'Kamaru Usman', 'Robert Whittaker', 'Tom Aspinall'};
            app.Fighter1DropDown.ValueChangedFcn = createCallbackFcn(app, @Fighter1DropDownValueChanged, true);
            app.Fighter1DropDown.Value = 'Islam Makhachev';
            app.Fighter2DropDownLabel = uilabel(app.Head2Head);
            app.Fighter2DropDownLabel.Text = 'Fighter 2';
            app.Fighter2DropDown = uidropdown(app.Head2Head);
            app.Fighter2DropDown.Items = {'Islam Makhachev', 'Jon Jones', 'Leon Edwards', 'Alex Pereira', 'Ilia Topuria', 'Sean O''Malley', 'Charles Oliveira', 'Alexander Volkanovski', 'Dricus Du Plessis', 'Alexandre Pantoja', 'Israel Adesanya', 'Tom Aspinall', 'Sean Strickland', 'Max Holloway', 'Aljamain Sterling', 'Georges St-Pierre', 'Khabib Nurmagomedov', 'Jorge Masvidal', 'Francis Ngannou', 'Conor McGregor', 'Justin Gaethje', 'Stipe Miocic', 'Dustin Poirier', 'Brandon Moreno', 'Merab Dvalishvili', 'Cory Sandhagen', 'Brian Ortega', 'Kamaru Usman', 'Robert Whittaker', 'Tom Aspinall'};
            app.Fighter2DropDown.ValueChangedFcn = createCallbackFcn(app, @Fighter2DropDownValueChanged, true);
            app.Fighter2DropDown.Value = 'Islam Makhachev';
            app.LossesEditFieldLabel = uilabel(app.Head2Head);
            app.LossesEditFieldLabel.Text = 'Losses';
            app.LossesEditField = uieditfield(app.Head2Head, 'numeric');
            app.LossesEditField_2Label = uilabel(app.Head2Head);
            app.LossesEditField_2Label.Text = 'Losses';
            app.LossesEditField_2 = uieditfield(app.Head2Head, 'numeric');
            app.FighterStanceToWins = uitab(app.TabGroup);
            app.FighterStanceToWins.Title = 'Fighter Stance & Wins';
            app.StanceVsRecordPlot = uiaxes(app.FighterStanceToWins);
            title(app.StanceVsRecordPlot, 'UFC Fighter Stance Versus Records')
            xlabel(app.StanceVsRecordPlot, 'Fighting Style')
            ylabel(app.StanceVsRecordPlot, 'Record')
            zlabel(app.StanceVsRecordPlot, 'Z')
            app.StanceVsRecordPlot.XLim = [0 3];
            app.StanceVsRecordPlot.YLim = [0 30];
            app.StanceVsRecordPlot.XTick = [];
            app.UFCFightersWinsLossesDrawsbyDifferentStancesLabel = uilabel(app.FighterStanceToWins);
            app.UFCFightersWinsLossesDrawsbyDifferentStancesLabel.Text = 'UFC Fighter''s Wins, Losses, & Draws by Different Stances ';
            app.WhichStanceDoYouWantButtonGroup = uibuttongroup(app.FighterStanceToWins);
            app.WhichStanceDoYouWantButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WhichStanceDoYouWantButtonGroupSelectionChanged, true);
            app.WhichStanceDoYouWantButtonGroup.Title = 'Which Stance Do You Want?';
            app.OrthodoxButton = uiradiobutton(app.WhichStanceDoYouWantButtonGroup);
            app.OrthodoxButton.Text = 'Orthodox (right-handed)';
            app.OrthodoxButton.Value = true;
            app.SouthpawButton = uiradiobutton(app.WhichStanceDoYouWantButtonGroup);
            app.SouthpawButton.Text = 'Southpaw (left-handed)';
            app.SwitchButton = uiradiobutton(app.WhichStanceDoYouWantButtonGroup);
            app.SwitchButton.Text = 'Switch (either hand)';
            app.NumberOfFightersEditField = uieditfield(app.FighterStanceToWins, 'numeric');
            app.NumberofFightersWithThisStanceLabel = uilabel(app.FighterStanceToWins);
            app.NumberofFightersWithThisStanceLabel.Text = 'Number of Fighters With This Stance';
            app.WingspanWinsTab = uitab(app.TabGroup);
            app.WingspanWinsTab.Title = 'Wingspan & Wins';
            app.WingspanScatter = uiaxes(app.WingspanWinsTab);
            title(app.WingspanScatter, 'Wingspan Versus Wins')
            xlabel(app.WingspanScatter, 'Wingspan')
            ylabel(app.WingspanScatter, 'Wins')
            zlabel(app.WingspanScatter, 'Z')
            app.WeightClassDropDownLabel = uilabel(app.WingspanWinsTab);
            app.WeightClassDropDownLabel.Text = 'Weight Class';
            app.WeightClassDropDown = uidropdown(app.WingspanWinsTab);
            app.WeightClassDropDown.Items = {'Flyweight', 'Bantamweight', 'Featherweight', 'Lightweight', 'Welterweight', 'Middleweight', 'Light Heavyweight', 'Heavyweight'};
            app.WeightClassDropDown.ValueChangedFcn = createCallbackFcn(app, @WeightClassDropDownValueChanged, true);
            app.WeightClassDropDown.Value = 'Featherweight';
            app.CorrelationBetweenWingspanWinsinDifferentWeightClassesLabel = uilabel(app.WingspanWinsTab);
            app.CorrelationBetweenWingspanWinsinDifferentWeightClassesLabel.Text = 'Correlation Between Wingspan & Wins in Different Weight Classes';
            app.FighterStanceAgeTab = uitab(app.TabGroup);
            app.FighterStanceAgeTab.Title = 'Fighter Stance & Age';
            app.FightersByAgePlot = uiaxes(app.FighterStanceAgeTab);
            title(app.FightersByAgePlot, 'Number of Fighters by Age for Each Stance')
            xlabel(app.FightersByAgePlot, 'Stance')
            ylabel(app.FightersByAgePlot, 'Number of Fighters')
            zlabel(app.FightersByAgePlot, 'Z')
            app.RelationshipBetweenFighterStanceBirthYearLabel = uilabel(app.FighterStanceAgeTab);
            app.RelationshipBetweenFighterStanceBirthYearLabel.Text = 'Relationship Between Fighter Stance & Birth Year ';
            app.BirthYearSliderLabel = uilabel(app.FighterStanceAgeTab);
            app.BirthYearSliderLabel.Text = 'Birth Year';
            app.BirthYearSlider = uislider(app.FighterStanceAgeTab);
            app.BirthYearSlider.Limits = [1970 2004];
            app.BirthYearSlider.ValueChangedFcn = createCallbackFcn(app, @BirthYearSliderValueChanged, true);
            app.BirthYearSlider.Value = 1970;
            app.SelectedYearEditFieldLabel = uilabel(app.FighterStanceAgeTab);
            app.SelectedYearEditFieldLabel.Text = 'Selected Year';
            app.SelectedYearEditField = uieditfield(app.FighterStanceAgeTab, 'numeric');
            app.SelectedYearEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedYearEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectUFC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end